classdef Oscilloscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OscilloscopeUIFigure   matlab.ui.Figure
        InfoPanel              matlab.ui.container.Panel
        T_Ch2EditField         matlab.ui.control.EditField
        T_Ch2EditFieldLabel    matlab.ui.control.Label
        T_Ch1EditField         matlab.ui.control.EditField
        T_Ch1EditFieldLabel    matlab.ui.control.Label
        Vpp_Ch2EditField       matlab.ui.control.EditField
        Vpp_Ch2EditFieldLabel  matlab.ui.control.Label
        Vpp_Ch1EditField       matlab.ui.control.EditField
        Vpp_Ch1EditFieldLabel  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        Wave_timeKnob          matlab.ui.control.Knob
        Wave_timeKnobLabel     matlab.ui.control.Label
        Volt_ScaleKnob         matlab.ui.control.Knob
        Volt_ScaleKnobLabel    matlab.ui.control.Label
        Time_ScaleKnob         matlab.ui.control.Knob
        Time_ScaleKnobLabel    matlab.ui.control.Label
        Chanel3Panel           matlab.ui.container.Panel
        InstantButton          matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
        DataLabel              matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        SineButton             matlab.ui.control.Button
        SourceDropDown         matlab.ui.control.DropDown
        SourceDropDownLabel    matlab.ui.control.Label
        Chanel1Panel           matlab.ui.container.Panel
        Vertical_1Knob         matlab.ui.control.Knob
        Vertical_1KnobLabel    matlab.ui.control.Label
        Frequency_1Knob        matlab.ui.control.Knob
        Frequency_1KnobLabel   matlab.ui.control.Label
        Amplitude_1Knob        matlab.ui.control.Knob
        Amplitude_1KnobLabel   matlab.ui.control.Label
        Switch_2               matlab.ui.control.Switch
        Chanel1DropDown        matlab.ui.control.DropDown
        Chanel2Panel           matlab.ui.container.Panel
        Vertical_2Knob         matlab.ui.control.Knob
        Vertical_2KnobLabel    matlab.ui.control.Label
        Frequency_2Knob        matlab.ui.control.Knob
        Frequency_2KnobLabel   matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        Switch_3               matlab.ui.control.Switch
        Amplitude_2Knob        matlab.ui.control.Knob
        Amplitude_2KnobLabel   matlab.ui.control.Label
        OscilloscopeLabel      matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        Switch                 matlab.ui.control.Switch
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isWork = false;
        connection = false;

        f = 0;
        a = 0;

        f_2 = 0;
        a_2 = 0;

        fs = 10000; % Sample rate
        t = 0; 

        fs_2 = 10000; % Sample rate
        t_2 = 0;

        x = 0;
        y = 0;

        y_2 = 0;

        RandTimer  % Timer object
        PlotLine   % Line object
    end
   
    
    methods (Access = private)
        
        function updatePlot(app)

            % Set the length of the signal
            T_2 = app.Wave_timeKnob.Value;
            T = app.Wave_timeKnob.Value;

            app.t = 0:1/app.fs:T;% Time vector
            app.t_2 = 0:1/app.fs_2:T_2;% Time vector

            if(app.isWork)
                ch_1 = string(app.Chanel1DropDown.Value);
                ch_2 = string(app.DropDown.Value);
                
                app.f = app.Frequency_1Knob.Value; % Frequency for ch1
                app.a = app.Amplitude_1Knob.Value; % Amplitude for ch1
                vert = app.Vertical_1Knob.Value; % Vertical Position for ch1
                
                app.f_2 = app.Frequency_2Knob.Value; % Frequency for ch2
                app.a_2 = app.Amplitude_2Knob.Value; % Amplitude for ch2
                vert_2 = app.Vertical_2Knob.Value; % Vertical Position for ch2

                if ch_1 == "Sine"   
                    % Generate a sine wave signal
                    app.y = app.a*sin(2*pi*app.f*app.t) + vert; % Signal
                elseif ch_1 == "Sawtooth"
                    % Generate a Sawtooth wave signal
                    app.y = app.a*sawtooth(2*pi*app.f*app.t) + vert; % sawtooth signal
                elseif ch_1 == "Square"
                    duty = 50; % Set the duty cycle (in percent)
                    % Generate a Square wave signal
                    app.y = app.a * square(2*pi*app.f*app.t, duty) + vert;
                elseif ch_1 == "Triangle"
                    % Generate a Triangle wave signal
                    app.y = app.a*sawtooth(2*pi*app.f*app.t,0.5) + vert;
                end
                
                if ch_2 == "Square"
                    duty_2 = 50; % Set the duty cycle (in percent)
                    % Generate a Square wave signal
                    app.y_2 = app.a_2 * square(2*pi*app.f_2*app.t_2, duty_2) + vert_2;
                elseif ch_2 == "Triangle"
                    % Generate a Triangle wave signal
                    app.y_2 = sawtooth(2*pi*app.f_2*app.t_2,0.5)*app.a_2+vert_2;
                elseif ch_2 == "Sine"
                    % Generate a Sine wave signal
                    app.y_2 = app.a_2*sin(2*pi*app.f_2*app.t_2)+vert_2; % Signal
                elseif ch_2 == "Sawtooth"
                    % Generate a Sawtooth wave signal
                    app.y_2 = app.a_2*sawtooth(2*pi*app.f_2*app.t_2)+vert_2; % sawtooth signal
                end

                hold(app.UIAxes, 'off');

                volt = app.Volt_ScaleKnob.Value;
                time = app.Time_ScaleKnob.Value;

                app.UIAxes.XLim = [0 time];
                app.UIAxes.YLim = [-volt volt];
                    
                chanel1 = string(app.Switch_2.Value);
                chanel2 = string(app.Switch_3.Value);

                if chanel1 == "On"
                    if chanel2 == "On"
                        plot(app.UIAxes,app.t,app.y,"b");
                        hold(app.UIAxes, 'on');
                        plot(app.UIAxes,app.t_2,app.y_2,"m");
                    else
                       plot(app.UIAxes,app.t,app.y,"b");
                    end
                else
                    if chanel2 == "On"
                        plot(app.UIAxes,app.t_2,app.y_2,"m");
                    else
                        plot(app.UIAxes,0,0);
                    end
                  
                end
                
                
                if (app.f < 0.00001)
                app.Vpp_Ch1EditField.Value = string(0);    
                else
                app.Vpp_Ch1EditField.Value = string(app.a*2);
                end 
                if (app.f_2 < 0.00001)
                app.Vpp_Ch2EditField.Value = string(0);    
                else
                app.Vpp_Ch2EditField.Value = string(app.a_2*2);
                end
                app.T_Ch1EditField.Value = string(1/(app.f+0.0000001));
                app.T_Ch2EditField.Value = string(1/(app.f_2+0.0000001));
            end
                   
                
        end 
        
        function RandTimerFcn(app,~,~)
            port = string(app.SourceDropDown.Value);
            model = 'Uno';
            app.a = arduino(port,model);
            app.connection = true;
            while(1)
                num = readVoltage(app.a, 'A0');
                app.EditField.Value = num;
                % Update YData in plot
                ydata = app.PlotLine.YData;
                ydata = circshift(ydata,1);
                ydata(1) = num;
                app.PlotLine.YData = ydata;
                if(~app.connection)
                    break;
                end
            end
       end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePlot(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            power = string(app.Switch.Value);
            if power == "On"
                app.isWork = true;
                updatePlot(app)
                app.Lamp.Color = [0 1 0];
            else
                app.isWork = false;
                app.Lamp.Color = [1 0 0];
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,0,0);
            end
            
        end

        % Button pushed function: SineButton
        function SineButtonPushed(app, event)
            % Create a connection to the Arduino board
            port = string(app.SourceDropDown.Value);
            model = 'Uno';
            app.a = arduino(port,model);
            app.fs=1000;
            app.t = 0:1/app.fs:1;
            app.f=10;
            app.connection = true;
            while(1)
                % Read data from pin A0
                data = readVoltage(app.a, 'A0');
                % Update the text box with the data
                app.EditField.Value = data;
                % Generate a sine wave signal with data
                app.x = data*sin(2*pi*app.f*app.t);
                % Plot signal
                plot(app.UIAxes,app.t,app.x);
                app.UIAxes.YLim = [-5 5];

                if(~app.connection)
                    break;
                end
            end
            
        end

        % Button pushed function: InstantButton
        function InstantButtonPushed(app, event)
            power = string(app.Switch.Value);
            if power == "On"
            % Configure x- and y- axis
            app.UIAxes.XLim = [0 60];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 5];
            
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.5, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
            if strcmp(app.RandTimer.Running, 'off')
               start(app.RandTimer);
            end
            
            end
        end

        % Value changed function: Amplitude_1Knob
        function Amplitude_1KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Frequency_1Knob
        function Frequency_1KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Vertical_1Knob
        function Vertical_1KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Amplitude_2Knob
        function Amplitude_2KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Frequency_2Knob
        function Frequency_2KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Vertical_2Knob
        function Vertical_2KnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Chanel1DropDown
        function Chanel1DropDownValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Time_ScaleKnob
        function Time_ScaleKnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Volt_ScaleKnob
        function Volt_ScaleKnobValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: Wave_timeKnob
        function Wave_timeKnobValueChanged(app, event)
            updatePlot(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.connection = false;
            % Stop the timer
            stop(app.RandTimer);
            plot(app.UIAxes,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OscilloscopeUIFigure = uifigure('Visible', 'off');
            app.OscilloscopeUIFigure.Name = 'Oscilloscope';
            app.UIAxes = uiaxes(app.OscilloscopeUIFigure);
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Volt')
            zlabel(app.UIAxes, 'Z')
            app.Switch = uiswitch(app.OscilloscopeUIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.OscilloscopeUIFigure);
            app.OscilloscopeLabel = uilabel(app.OscilloscopeUIFigure);
            app.OscilloscopeLabel.Text = 'Oscilloscope';
            app.Chanel2Panel = uipanel(app.OscilloscopeUIFigure);
            app.Chanel2Panel.Title = 'Chanel 2';
            app.Amplitude_2KnobLabel = uilabel(app.Chanel2Panel);
            app.Amplitude_2KnobLabel.Text = 'Amplitude_2';
            app.Amplitude_2Knob = uiknob(app.Chanel2Panel, 'continuous');
            app.Amplitude_2Knob.Limits = [0 10];
            app.Amplitude_2Knob.ValueChangedFcn = createCallbackFcn(app, @Amplitude_2KnobValueChanged, true);
            app.Switch_3 = uiswitch(app.Chanel2Panel, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.DropDown = uidropdown(app.Chanel2Panel);
            app.DropDown.Items = {'Square', 'Triangle', 'Sine', 'Sawtooth'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Square';
            app.Frequency_2KnobLabel = uilabel(app.Chanel2Panel);
            app.Frequency_2KnobLabel.Text = 'Frequency_2';
            app.Frequency_2Knob = uiknob(app.Chanel2Panel, 'continuous');
            app.Frequency_2Knob.Limits = [0 50];
            app.Frequency_2Knob.ValueChangedFcn = createCallbackFcn(app, @Frequency_2KnobValueChanged, true);
            app.Frequency_2Knob.Value = 50;
            app.Vertical_2KnobLabel = uilabel(app.Chanel2Panel);
            app.Vertical_2KnobLabel.Text = 'Vertical_2';
            app.Vertical_2Knob = uiknob(app.Chanel2Panel, 'continuous');
            app.Vertical_2Knob.Limits = [-10 10];
            app.Vertical_2Knob.ValueChangedFcn = createCallbackFcn(app, @Vertical_2KnobValueChanged, true);
            app.Vertical_2Knob.Value = -2;
            app.Chanel1Panel = uipanel(app.OscilloscopeUIFigure);
            app.Chanel1Panel.Title = 'Chanel 1';
            app.Chanel1DropDown = uidropdown(app.Chanel1Panel);
            app.Chanel1DropDown.Items = {'Sine', 'Sawtooth', 'Square', 'Triangle'};
            app.Chanel1DropDown.ValueChangedFcn = createCallbackFcn(app, @Chanel1DropDownValueChanged, true);
            app.Chanel1DropDown.Value = 'Sine';
            app.Switch_2 = uiswitch(app.Chanel1Panel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Amplitude_1KnobLabel = uilabel(app.Chanel1Panel);
            app.Amplitude_1KnobLabel.Text = 'Amplitude_1';
            app.Amplitude_1Knob = uiknob(app.Chanel1Panel, 'continuous');
            app.Amplitude_1Knob.Limits = [0 10];
            app.Amplitude_1Knob.ValueChangedFcn = createCallbackFcn(app, @Amplitude_1KnobValueChanged, true);
            app.Frequency_1KnobLabel = uilabel(app.Chanel1Panel);
            app.Frequency_1KnobLabel.Text = 'Frequency_1';
            app.Frequency_1Knob = uiknob(app.Chanel1Panel, 'continuous');
            app.Frequency_1Knob.ValueChangedFcn = createCallbackFcn(app, @Frequency_1KnobValueChanged, true);
            app.Frequency_1Knob.Value = 100;
            app.Vertical_1KnobLabel = uilabel(app.Chanel1Panel);
            app.Vertical_1KnobLabel.Text = 'Vertical_1';
            app.Vertical_1Knob = uiknob(app.Chanel1Panel, 'continuous');
            app.Vertical_1Knob.Limits = [-10 10];
            app.Vertical_1Knob.ValueChangedFcn = createCallbackFcn(app, @Vertical_1KnobValueChanged, true);
            app.Vertical_1Knob.Value = 6;
            app.Chanel3Panel = uipanel(app.OscilloscopeUIFigure);
            app.Chanel3Panel.Title = 'Chanel 3';
            app.SourceDropDownLabel = uilabel(app.Chanel3Panel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.Chanel3Panel);
            app.SourceDropDown.Items = {'None', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SourceDropDown.Value = 'None';
            app.SineButton = uibutton(app.Chanel3Panel, 'push');
            app.SineButton.ButtonPushedFcn = createCallbackFcn(app, @SineButtonPushed, true);
            app.SineButton.Text = 'Sine';
            app.EditField = uieditfield(app.Chanel3Panel, 'numeric');
            app.DataLabel = uilabel(app.Chanel3Panel);
            app.DataLabel.Text = 'Data';
            app.DisconnectButton = uibutton(app.Chanel3Panel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.InstantButton = uibutton(app.Chanel3Panel, 'push');
            app.InstantButton.ButtonPushedFcn = createCallbackFcn(app, @InstantButtonPushed, true);
            app.InstantButton.Text = 'Instant';
            app.Panel = uipanel(app.OscilloscopeUIFigure);
            app.Time_ScaleKnobLabel = uilabel(app.Panel);
            app.Time_ScaleKnobLabel.Text = 'Time_Scale';
            app.Time_ScaleKnob = uiknob(app.Panel, 'continuous');
            app.Time_ScaleKnob.Limits = [0.1 2];
            app.Time_ScaleKnob.ValueChangedFcn = createCallbackFcn(app, @Time_ScaleKnobValueChanged, true);
            app.Time_ScaleKnob.Value = 0.1;
            app.Volt_ScaleKnobLabel = uilabel(app.Panel);
            app.Volt_ScaleKnobLabel.Text = 'Volt_Scale';
            app.Volt_ScaleKnob = uiknob(app.Panel, 'continuous');
            app.Volt_ScaleKnob.Limits = [2 20];
            app.Volt_ScaleKnob.ValueChangedFcn = createCallbackFcn(app, @Volt_ScaleKnobValueChanged, true);
            app.Volt_ScaleKnob.Value = 20;
            app.Wave_timeKnobLabel = uilabel(app.Panel);
            app.Wave_timeKnobLabel.Text = 'Wave_time';
            app.Wave_timeKnob = uiknob(app.Panel, 'continuous');
            app.Wave_timeKnob.Limits = [0 10];
            app.Wave_timeKnob.ValueChangedFcn = createCallbackFcn(app, @Wave_timeKnobValueChanged, true);
            app.Wave_timeKnob.Value = 1;
            app.InfoPanel = uipanel(app.OscilloscopeUIFigure);
            app.InfoPanel.Title = 'Info';
            app.Vpp_Ch1EditFieldLabel = uilabel(app.InfoPanel);
            app.Vpp_Ch1EditFieldLabel.Text = 'Vpp_Ch1';
            app.Vpp_Ch1EditField = uieditfield(app.InfoPanel, 'text');
            app.Vpp_Ch2EditFieldLabel = uilabel(app.InfoPanel);
            app.Vpp_Ch2EditFieldLabel.Text = 'Vpp_Ch2';
            app.Vpp_Ch2EditField = uieditfield(app.InfoPanel, 'text');
            app.T_Ch1EditFieldLabel = uilabel(app.InfoPanel);
            app.T_Ch1EditFieldLabel.Text = 'T_Ch1';
            app.T_Ch1EditField = uieditfield(app.InfoPanel, 'text');
            app.T_Ch2EditFieldLabel = uilabel(app.InfoPanel);
            app.T_Ch2EditFieldLabel.Text = 'T_Ch2';
            app.T_Ch2EditField = uieditfield(app.InfoPanel, 'text');
            % Show the figure after all components are created
            app.OscilloscopeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Oscilloscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OscilloscopeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OscilloscopeUIFigure)
        end
    end
end